classdef gameBoardGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        ConnectButton     matlab.ui.control.Button
        Image             matlab.ui.control.Image
        PlayButton        matlab.ui.control.Button
        Player2Lamp       matlab.ui.control.Lamp
        Player2LampLabel  matlab.ui.control.Label
        Player1Lamp       matlab.ui.control.Lamp
        Player1LampLabel  matlab.ui.control.Label
        UITable           matlab.ui.control.Table
        ResetButton       matlab.ui.control.Button
        Column7Button     matlab.ui.control.Button
        Column6Button     matlab.ui.control.Button
        Column5Button     matlab.ui.control.Button
        Column4Button     matlab.ui.control.Button
        Column3Button     matlab.ui.control.Button
        Column2Button     matlab.ui.control.Button
        Column1Button     matlab.ui.control.Button
    end

    
    properties (Access = private)
        player % Either = 1 or 2, depending on who's turn it is
        board  % Array containing all play spaces
        winner
        sim
        clientID
        gripperID
        Z_motor
        X_motor
        x_columnPositions = [25, 50];
        y_columnPosition = 100;
        z_columnPosition = 100;
    end
    
    methods (Access = private)
        function updateCamera(app)
            [err,res,img] = sim.simxGetVisionSensorImage(app.clientID, app.camera, 0, sim.simx_opmode_buffer);
            app.Image = img;
        end

        function changeParentChild(app, childID, grip)
            %[number returnCode]=simxSetObjectParent(number clientID,number objectHandle,number parentObject,boolean keepInPlace,number operationMode)
            returnCode = simxSetObjectParent(app.clientID, childID, app.gripperID, grip, simx_opmode_oneshot);
        end

        function getPiece(app, pieceID, target)
            % Get piece location
            % [number returnCode,array position]=simxGetObjectPosition(number clientID,number objectHandle,number relativeToObjectHandle,number operationMode)
            [returnCode, position] = imxGetObjectPosition(app.clientID, pieceID, -1, simx_opmode_streaming); % -1 indicates absolute position, streaming opmode for first call
            
            % Go to piece location
            app.moveGantry(position)

            % Change parent/child to grab piece
            app.changeParentChild(pieceID, true);

            % Move to drop off
            app.moveGantry(app.dropoffLocation);

            % Release piece
            app.changeParentChild(pieceID, false);

        end

        function moveGantry(app, position)
            disp('Moving to ' + string(position))
            % [number returnCode]=simxSetJointPosition(number clientID,number jointHandle,number position,number operationMode)
            
            % Move Z
            ZreturnCode = simxSetJointPosition(app.clientID, app.Z_motor, position(3), simx_opmode_oneshot);

            % Move X
            XreturnCode = simxSetJointPosition(app.clientID, app.X_motor, position(1), simx_opmode_oneshot);
        end

        function findPiece(app)
            % Find available chips for player
            
        end


        function changePlayer(app)
            if app.player == 1
               app.player = 2;
               app.Player1Lamp.Enable = "off";
               app.Player2Lamp.Enable = "on";
               disp("Player 2's turn")
            else
               app.player = 1;
               app.Player1Lamp.Enable = "on";
               app.Player2Lamp.Enable = "off";
               disp("Player 1's turn")

            end
        end

        function addPiece(app, column)
            app.updateCamera();
            for i = 1:6
                current = app.UITable(7-i,column);
                if current == 0
                    app.UITable(7-i,column) = app.player;
                    disp(app.UITable)
                    
                    % Check if won
                    if ~app.didWin() % If player did not win
                        % app.getPiece(app.findPiece(), [app.columnpositions(column), app.y_columnPosition, app.z_columnPosition])
                        
                        app.changePlayer();
                    end
                    break
                end
            end
        end

    function ret = didWin(app) % This will be hard
        % Find if there's 4 in a row
        disp("didWin()")
        counter = 0;
        lastVal = app.player;
        for rowIndex = 1:6
            for columnIndex = 1:7
                val = app.UITable(rowIndex, columnIndex);
                if val == lastVal
                    if val == app.player
                        counter = counter + 1;
                        if counter >= 3
                            ret = true;
                            disp("Player " + string(app.player) + " wins!")
                            return
                        end
                    end
                end
                lastVal = val; 
            end
        end
    
        % Find if there's 4 in a column
        counter = 0;
        lastVal = app.player;
        for columnIndex = 1:7
            for rowIndex = 1:6
                val = app.UITable (rowIndex, columnIndex);
                if val == lastVal
                    if val == app.player
                        counter = counter + 1;
                        if counter >= 3
                            ret = true;
                            disp("Player " + string(app.player) + " wins!")
                        end
                    end
                end
                lastVal = val;
            end
        end

        ret = false;
    end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            disp("Reset")
            app.PlayButtonPushed()
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            disp("Play")
            app.UITable = [[0,0,0,0,0,0,0];
                         [0,0,0,0,0,0,0];
                         [0,0,0,0,0,0,0];
                         [0,0,0,0,0,0,0];
                         [0,0,0,0,0,0,0];
                         [0,0,0,0,0,0,0];
                        ];
            app.player = 1;
            app.Player2Lamp.Enable = "off";
        end

        % Button pushed function: Column1Button
        function Column1ButtonPushed(app, event)
            app.addPiece(1);

        end

        % Button pushed function: Column2Button
        function Column2ButtonPushed(app, event)
            app.addPiece(2);
            
        end

        % Button pushed function: Column3Button
        function Column3ButtonPushed(app, event)
            app.addPiece(3);
            
        end

        % Button pushed function: Column4Button
        function Column4ButtonPushed(app, event)
            app.addPiece(4);
            
        end

        % Button pushed function: Column5Button
        function Column5ButtonPushed(app, event)
            app.addPiece(5);
            
        end

        % Button pushed function: Column6Button
        function Column6ButtonPushed(app, event)
            app.addPiece(6);
            
        end

        % Button pushed function: Column7Button
        function Column7ButtonPushed(app, event)
            app.addPiece(7);
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)

            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.sim = remApi('remoteApi'); % Using the prototype file (remoteApiProto.m)
            app.sim.simxFinish(-1); % Close all opened connections
            app.clientID = app.sim.simxStart('127.0.0.1', 19999, true, true, 5000, 5);

            if (app.clientID > -1)
                disp('Connected to remote API server'); % Displays message in MATLAB
                app.sim.simxAddStatusbarMessage(app.clientID, 'Hello CoppeliaSim!', app.sim.simx_opmode_oneshot); % Displays message in Copellia
                pause(2)
                
                % Aquire handle for objects we want to read or manipulate
                [~, app.Z_Motor] = app.sim.simxGetObjectHandle(app.clientID, 'Z_MOTOR', app.sim.simx_opmode_blocking);
                [~, app.X_Motor] = app.sim.simxGetObjectHandle(app.clientID, 'X_MOTOR', app.sim.simx_opmode_blocking);
                [~, app.gripperID] = app.sim.simxGetObjectHandle(app.clientID, 'X_FRAME', app.sim.simx_opmode_blocking);
                app.camera = app.sim.simxGetObjectHandle(app.clientID, 'Vision_sensor', app.sim.simx_opmode_streaming);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
            % Close connection
            app.sim.simxFinish(-1);  % End connection
            app.sim.delete();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Column1Button = uibutton(app.UIFigure, 'push');
            app.Column1Button.ButtonPushedFcn = createCallbackFcn(app, @Column1ButtonPushed, true);
            app.Column1Button.Text = 'Column 1';
            app.Column2Button = uibutton(app.UIFigure, 'push');
            app.Column2Button.ButtonPushedFcn = createCallbackFcn(app, @Column2ButtonPushed, true);
            app.Column2Button.Text = 'Column 2';
            app.Column3Button = uibutton(app.UIFigure, 'push');
            app.Column3Button.ButtonPushedFcn = createCallbackFcn(app, @Column3ButtonPushed, true);
            app.Column3Button.Text = 'Column 3';
            app.Column4Button = uibutton(app.UIFigure, 'push');
            app.Column4Button.ButtonPushedFcn = createCallbackFcn(app, @Column4ButtonPushed, true);
            app.Column4Button.Text = 'Column 4';
            app.Column5Button = uibutton(app.UIFigure, 'push');
            app.Column5Button.ButtonPushedFcn = createCallbackFcn(app, @Column5ButtonPushed, true);
            app.Column5Button.Text = 'Column 5';
            app.Column6Button = uibutton(app.UIFigure, 'push');
            app.Column6Button.ButtonPushedFcn = createCallbackFcn(app, @Column6ButtonPushed, true);
            app.Column6Button.Text = 'Column 6';
            app.Column7Button = uibutton(app.UIFigure, 'push');
            app.Column7Button.ButtonPushedFcn = createCallbackFcn(app, @Column7ButtonPushed, true);
            app.Column7Button.Text = 'Column 7';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'Column 6'; 'Column 7'};
            app.UITable.RowName = {'Row 1, Row 2, Row 3, Row 4, Row 5, Row 6'};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.Player1LampLabel = uilabel(app.UIFigure);
            app.Player1LampLabel.Text = 'Player 1';
            app.Player1Lamp = uilamp(app.UIFigure);
            app.Player2LampLabel = uilabel(app.UIFigure);
            app.Player2LampLabel.Text = 'Player 2';
            app.Player2Lamp = uilamp(app.UIFigure);
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.Image = uiimage(app.UIFigure);
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gameBoardGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end